classdef LearnerCompanionPDE < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LearningFlashCardUIFigure  matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        Option4Label               matlab.ui.control.Label
        Option3Label               matlab.ui.control.Label
        Option2Label               matlab.ui.control.Label
        Option1Label               matlab.ui.control.Label
        NextButton                 matlab.ui.control.Button
        HintButton                 matlab.ui.control.Button
        QuestionLabel              matlab.ui.control.Label
        Option1                    matlab.ui.control.Button
        Option2                    matlab.ui.control.Button
        Option3                    matlab.ui.control.Button
        Option4                    matlab.ui.control.Button
    end

    
    properties (Access = private)

        FlashCard % Full Flash Card Library
        Solution  % Current flash card solution
        Explanation % Current flash card explanation
        Hint % Current flash card hint

    end
    
    methods (Access = private)
        
        function app = UpdateQuestion(app)
            
            % Retrieve a random card
            NumQuestions = size(app.FlashCard,1);
            FlashCard = app.FlashCard(randi([1 NumQuestions]));

            % Shuffle the options
            FlashCard.Options = {FlashCard.Options{randperm(4)}}';
            
            % Display question and options
            app.QuestionLabel.Text = FlashCard.Question;
            app.Option1Label.Text = FlashCard.Options{1};
            app.Option2Label.Text = FlashCard.Options{2};
            app.Option3Label.Text = FlashCard.Options{3};
            app.Option4Label.Text = FlashCard.Options{4};

            app.Option1.Tooltip = FlashCard.Options{1};
            app.Option2.Tooltip = FlashCard.Options{2};
            app.Option3.Tooltip = FlashCard.Options{3};
            app.Option4.Tooltip = FlashCard.Options{4};

            % Set the background color to default
            app.Option1.BackgroundColor = [0.96,0.96,0.96];
            app.Option2.BackgroundColor = [0.96,0.96,0.96];
            app.Option3.BackgroundColor = [0.96,0.96,0.96];
            app.Option4.BackgroundColor = [0.96,0.96,0.96];

            % Save solution, explanation and hint
            app.Solution = FlashCard.Answer;
            app.Explanation = FlashCard.Explanation;
            app.Hint = FlashCard.Hint;

        end
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Read the database
            txt = fileread("DatabasePDE.json");
            app.FlashCard = jsondecode(txt);

            app.Option1.Text = "Option 1";
            app.Option2.Text = "Option 2";
            app.Option3.Text = "Option 3";
            app.Option4.Text = "Option 4";
            % Display the first flash card
            UpdateQuestion(app);

        end

        % Button pushed function: Option1, Option2, Option3, Option4
        function CheckAnswer(app, event)
            
            if strcmp(event.Source.Tooltip,app.Solution)
                % Show success in green
                event.Source.BackgroundColor = [0.1 1.0 0.1];

                % Disable every button
                app.NextButton.Enable = "off";
                app.HintButton.Enable = "off";
                app.Option1.Enable = "off";
                app.Option2.Enable = "off";
                app.Option3.Enable = "off";
                app.Option4.Enable = "off";

                % Show explanation
                app.QuestionLabel.Text = app.Explanation;
                pause(4)

                % Load new card
                UpdateQuestion(app);

                % Enable every button
                app.NextButton.Enable = "on";
                app.HintButton.Enable = "on";
                app.Option1.Enable = "on";
                app.Option2.Enable = "on";
                app.Option3.Enable = "on";
                app.Option4.Enable = "on";

            else
                % Show error in red
                event.Source.BackgroundColor = [1.0 0.1 0.1];
            end

        end

        % Button pushed function: NextButton
        function NextCard(app, event)
            UpdateQuestion(app);
        end

        % Button pushed function: HintButton
        function HintButtonPushed(app, event)
            % Disable every button
            app.NextButton.Enable = "off";
            app.HintButton.Enable = "off";
            app.Option1.Enable = "off";
            app.Option2.Enable = "off";
            app.Option3.Enable = "off";
            app.Option4.Enable = "off";

            % Show hint
            Question = app.QuestionLabel.Text;
            app.QuestionLabel.Text = app.Hint;
            pause(3)
            app.QuestionLabel.Text = Question;

            % Enable every button
            app.NextButton.Enable = "on";
            app.HintButton.Enable = "on";
            app.Option1.Enable = "on";
            app.Option2.Enable = "on";
            app.Option3.Enable = "on";
            app.Option4.Enable = "on";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LearningFlashCardUIFigure = uifigure('Visible', 'off');
            app.LearningFlashCardUIFigure.Name = 'Learning Flash Card';
            app.GridLayout = uigridlayout(app.LearningFlashCardUIFigure);
            app.Option4 = uibutton(app.GridLayout, 'push');
            app.Option4.ButtonPushedFcn = createCallbackFcn(app, @CheckAnswer, true);
            app.Option4.Text = '';
            app.Option3 = uibutton(app.GridLayout, 'push');
            app.Option3.ButtonPushedFcn = createCallbackFcn(app, @CheckAnswer, true);
            app.Option3.Text = '';
            app.Option2 = uibutton(app.GridLayout, 'push');
            app.Option2.ButtonPushedFcn = createCallbackFcn(app, @CheckAnswer, true);
            app.Option2.Text = '';
            app.Option1 = uibutton(app.GridLayout, 'push');
            app.Option1.ButtonPushedFcn = createCallbackFcn(app, @CheckAnswer, true);
            app.Option1.Text = '';
            app.QuestionLabel = uilabel(app.GridLayout);
            app.QuestionLabel.Text = 'Question Area';
            app.HintButton = uibutton(app.GridLayout, 'push');
            app.HintButton.ButtonPushedFcn = createCallbackFcn(app, @HintButtonPushed, true);
            app.HintButton.Text = 'Hint';
            app.NextButton = uibutton(app.GridLayout, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextCard, true);
            app.NextButton.Text = 'Next Question';
            app.Option1Label = uilabel(app.GridLayout);
            app.Option1Label.Interpreter = 'latex';
            app.Option2Label = uilabel(app.GridLayout);
            app.Option2Label.Interpreter = 'latex';
            app.Option2Label.Text = 'Label2';
            app.Option3Label = uilabel(app.GridLayout);
            app.Option3Label.Interpreter = 'latex';
            app.Option3Label.Text = 'Label3';
            app.Option4Label = uilabel(app.GridLayout);
            app.Option4Label.Interpreter = 'latex';
            app.Option4Label.Text = 'Label4';
            % Show the figure after all components are created
            app.LearningFlashCardUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LearnerCompanionPDE

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LearningFlashCardUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LearningFlashCardUIFigure)
        end
    end
end